classdef FlightLogAnalysisApplication < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        ControlPanel          matlab.ui.container.Panel
        GridLayout3           matlab.ui.container.GridLayout
        ShowHidePannerButton  matlab.ui.control.Button
        SaveFigurepngButton   matlab.ui.control.Button
        SaveFigurefigButton   matlab.ui.control.Button
        NewFigureButton       matlab.ui.control.Button
        NewAxesScatterButton  matlab.ui.control.Button
        NewAxesLineButton     matlab.ui.control.Button
        EditAxesButton        matlab.ui.control.Button
        DeleteSelectedButton  matlab.ui.control.Button
        EntityTreePanel       matlab.ui.container.Panel
        Tree                  matlab.ui.container.Tree
        DataPanel             matlab.ui.container.Panel
        GridLayout2           matlab.ui.container.GridLayout
        Label                 matlab.ui.control.Label
        LoadDataButton        matlab.ui.control.Button
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = public)
        DataManager
        
        PlotManager
        SelectedNode
        CurrentData
        Figures struct
        NextFigureId = 1
        NextAxesId = 1
        PannerVisible logical = true
        TabGroup
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [file, path] = uigetfile('*.csv', 'Select Flight Log Data');
            if isequal(file, 0)
                return;
            end
            
            filename = fullfile(path, file);
            
            try
                % Charger DataManager depuis src/
                addpath(fullfile(fileparts(mfilename('fullpath')), 'src'));
                dataManager = FlightDataManager(); 
                
                success = dataManager.loadData(filename);
                if success
                    app.Label.Text = sprintf('Loaded: %s', file);
                    dataManager.calculateAll();
                    app.CurrentData = dataManager;
                    app.DataManager = dataManager;
                    
                    app.Label.Text = [app.Label.Text, ' - Prêt à créer des figures'];
                else
                    app.Label.Text = 'Error loading file';
                end
            catch ME
                app.Label.Text = sprintf('Error: %s', ME.message);
            end
        end

        % Button pushed function: DeleteSelectedButton
        function DeleteSelectedButtonPushed(app, event)
            [figId, axesId] = SupportFunctions.getSelectedIds(app);
            if isempty(figId)
                uialert(app.UIFigure, 'Please select a Figure or Axes in the tree first.', 'No Selection');
                return;
            end
            
            if ~isempty(axesId)
                SupportFunctions.deleteAxes(app, figId, axesId);
            else
                SupportFunctions.deleteFigure(app, figId);
            end
        end

        % Button pushed function: EditAxesButton
        function EditAxesButtonPushed(app, event)
            if isempty(app.SelectedNode) || ~startsWith(app.SelectedNode.Text, 'Axes')
                uialert(app.UIFigure, 'Please select an axes to edit.', 'No Axes Selected');
                return;
            end
            
            parentNode = app.SelectedNode.Parent;
            figId = str2double(extractAfter(parentNode.Text, 'Figure '));
            axesId = str2double(extractAfter(app.SelectedNode.Text, 'Axes '));
            
            % Ouvrir dialog de configuration simple
            app.configureAxes(figId, axesId);
        end

        % Button pushed function: NewAxesLineButton
        function NewAxesLineButtonPushed(app, event)
            [figId, ~] = SupportFunctions.getSelectedIds(app);
            if isempty(figId)
                uialert(app.UIFigure, 'Please select a FIGURE in the tree first.', 'No Figure Selected');
                return;
            end
            
            SupportFunctions.addNewAxes(app, figId, 'line');
            app.Label.Text = sprintf('Nouvel axe Line ajouté à Figure %d', figId);
        end

        % Button pushed function: NewAxesScatterButton
        function NewAxesScatterButtonPushed(app, event)
            if isempty(app.SelectedNode) || ~startsWith(app.SelectedNode.Text, 'Figure')
                uialert(app.UIFigure, 'Please select a figure first.', 'No Figure Selected');
                return;
            end
            
            figId = str2double(extractAfter(app.SelectedNode.Text, 'Figure '));
            app.addNewAxes(figId, 'scatter');
        end

        % Button pushed function: NewFigureButton
        function NewFigureButtonPushed(app, event)
            try
                SupportFunctions.createNewFigure(app);
            catch ME
                app.Label.Text = sprintf('Error creating figure: %s', ME.message);
            end
        end

        % Button pushed function: SaveFigurefigButton
        function SaveFigurefigButtonPushed(app, event)
            if isempty(app.SelectedNode) || ~startsWith(app.SelectedNode.Text, 'Figure')
                uialert(app.UIFigure, 'Please select a figure to save.', 'No Figure Selected');
                return;
            end
            
            figId = str2double(extractAfter(app.SelectedNode.Text, 'Figure '));
            app.saveFigureAsFIG(figId);
        end

        % Button pushed function: SaveFigurepngButton
        function SaveFigurepngButtonPushed(app, event)
            try
                [file, path] = uiputfile('*.png', 'Save Figure as PNG');
                if isequal(file, 0)
                    return;
                end
                filename = fullfile(path, file);
                
                % Capturer la figure
                exportgraphics(app.TabGroup, filename, 'Resolution', 300);
                app.Label.Text = sprintf('Saved: %s', file);
            catch ME
                app.Label.Text = sprintf('Save error: %s', ME.message);
            end
        end

        % Button pushed function: ShowHidePannerButton
        function ShowHidePannerButtonPushed(app, event)
            try
                if strcmp(app.UIAxes5.Visible, 'on')
                    % hide panners for both figures
                    app.UIAxes5.Visible = 'off';
                    app.UIAxes5_2.Visible = 'off';
                    app.ShowHidePannerButton.Text = 'Show Panner';
                else
                    % show panners for both figures
                    app.UIAxes5.Visible = 'on';
                    app.UIAxes5_2.Visible = 'on';
                    app.ShowHidePannerButton.Text = 'Hide Panner';
                end
            catch ME
                app.Label.Text = sprintf('Panner error: %s', ME.message);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.DataPanel = uipanel(app.GridLayout);
            app.DataPanel.Title = 'Data';
            app.GridLayout2 = uigridlayout(app.DataPanel);
            app.LoadDataButton = uibutton(app.GridLayout2, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.Label = uilabel(app.GridLayout2);
            app.EntityTreePanel = uipanel(app.GridLayout);
            app.EntityTreePanel.Title = 'Entity Tree';
            app.Tree = uitree(app.EntityTreePanel);
            app.Tree.Multiselect = 'on';
            app.ControlPanel = uipanel(app.GridLayout);
            app.ControlPanel.Title = 'Control Panel';
            app.GridLayout3 = uigridlayout(app.ControlPanel);
            app.DeleteSelectedButton = uibutton(app.GridLayout3, 'push');
            app.DeleteSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedButtonPushed, true);
            app.DeleteSelectedButton.Text = 'Delete Selected';
            app.EditAxesButton = uibutton(app.GridLayout3, 'push');
            app.EditAxesButton.ButtonPushedFcn = createCallbackFcn(app, @EditAxesButtonPushed, true);
            app.EditAxesButton.Text = 'Edit Axes';
            app.NewAxesLineButton = uibutton(app.GridLayout3, 'push');
            app.NewAxesLineButton.ButtonPushedFcn = createCallbackFcn(app, @NewAxesLineButtonPushed, true);
            app.NewAxesLineButton.Text = 'New Axes (Line)';
            app.NewAxesScatterButton = uibutton(app.GridLayout3, 'push');
            app.NewAxesScatterButton.ButtonPushedFcn = createCallbackFcn(app, @NewAxesScatterButtonPushed, true);
            app.NewAxesScatterButton.Text = 'New Axes (Scatter)';
            app.NewFigureButton = uibutton(app.GridLayout3, 'push');
            app.NewFigureButton.ButtonPushedFcn = createCallbackFcn(app, @NewFigureButtonPushed, true);
            app.NewFigureButton.Text = 'New Figure';
            app.SaveFigurefigButton = uibutton(app.GridLayout3, 'push');
            app.SaveFigurefigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigurefigButtonPushed, true);
            app.SaveFigurefigButton.Text = 'Save Figure (.fig)';
            app.SaveFigurepngButton = uibutton(app.GridLayout3, 'push');
            app.SaveFigurepngButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigurepngButtonPushed, true);
            app.SaveFigurepngButton.Text = 'Save Figure (.png)';
            app.ShowHidePannerButton = uibutton(app.GridLayout3, 'push');
            app.ShowHidePannerButton.ButtonPushedFcn = createCallbackFcn(app, @ShowHidePannerButtonPushed, true);
            app.ShowHidePannerButton.Text = 'Show / Hide Panner';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FlightLogAnalysisApplication

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end